classdef runapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ComputationMethodsLabel         matlab.ui.control.Label
        NumberofMultiStartPointsEditField  matlab.ui.control.NumericEditField
        NumberofMultiStartPointsEditFieldLabel  matlab.ui.control.Label
        TotalTimeStepsEditField         matlab.ui.control.NumericEditField
        RegularizationparameterEditFieldLabel_6  matlab.ui.control.Label
        RunButton                       matlab.ui.control.StateButton
        ParmetersoftheFrequenciesPanel  matlab.ui.container.Panel
        InitializeButton                matlab.ui.control.Button
        TimeStepLengthEditField         matlab.ui.control.NumericEditField
        RegularizationparameterEditFieldLabel_5  matlab.ui.control.Label
        TrainingTimeStepsEditField      matlab.ui.control.NumericEditField
        RegularizationparameterEditFieldLabel_4  matlab.ui.control.Label
        TransientStepsEditField         matlab.ui.control.NumericEditField
        RegularizationparameterEditFieldLabel_3  matlab.ui.control.Label
        ReservoirConstantEditField      matlab.ui.control.NumericEditField
        ReservoirConstantEditFieldLabel  matlab.ui.control.Label
        HarmonicMeanWeightEditField     matlab.ui.control.NumericEditField
        HarmonicMeanWeightEditFieldLabel  matlab.ui.control.Label
        RegularizationParameterEditField  matlab.ui.control.NumericEditField
        RegularizationParameterEditFieldLabel  matlab.ui.control.Label
        NumberofReservoirNodesEditField  matlab.ui.control.NumericEditField
        NumberofReservoirNodesEditFieldLabel  matlab.ui.control.Label
        NumberofInputFrequenciesEditField  matlab.ui.control.NumericEditField
        NumberofInputFrequenciesEditFieldLabel  matlab.ui.control.Label
        TimeParametersLabel             matlab.ui.control.Label
        OptimizationParametersLabel     matlab.ui.control.Label
        ReservoirParametersLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        FreqInputFields % To store handles of dynamic input fields
        ResultsData  % To store results data for showing in UITable
    end
    
    methods (Access = private)
         function GenerateFreqInputs(app)
                K = app.NumberofInputFrequenciesEditField.Value;  % Number of frequencies
                
                % Delete previous fields
                delete(app.ParmetersoftheFrequenciesPanel.Children)
                
                % Panel dimensions
                panelHeight = app.ParmetersoftheFrequenciesPanel.Position(4);
                
                y = panelHeight - 60;  % Start near the top
                spacingY = 35;         % Vertical spacing
                spacingX = 150;        % Horizontal spacing between columns
                fieldWidth = 110;
                fieldHeight = 22;
                xStart = 80;           % Start X for numeric fields (after freq number column)
                headerWidth = 70;      % Width for frequency number column
                
                % Column headers including the frequency number column
                headers = {'Freq No.', 'Amplitude (Input)', 'Amplitude (Output)', 'Frequencies', 'Output Phase'};
                
                % Create headers
                for col = 1:length(headers)
                    lblHeader = uilabel(app.ParmetersoftheFrequenciesPanel);
                    lblHeader.Text = headers{col};
                    lblHeader.FontWeight = 'bold';
                    if col == 1
                        % Position for frequency number column header
                        lblHeader.Position = [10, y, headerWidth, fieldHeight];
                    else
                        % Positions for other column headers
                        lblHeader.Position = [xStart + (col-2)*spacingX, y, fieldWidth, fieldHeight];
                    end
                end
                
                % Prepare storage for dynamic numeric fields handles
                app.FreqInputFields = gobjects(K, length(headers)-1);  % K rows, 4 columns excluding freq number
                
                % Move down for input rows
                y = y - spacingY;
                
                for i = 1:K
                    % Frequency number label in first column
                    freqLabel = uilabel(app.ParmetersoftheFrequenciesPanel);
                    freqLabel.Text = sprintf('%d', i);
                    freqLabel.FontWeight = 'bold';
                    freqLabel.Position = [10, y, headerWidth, fieldHeight];
                    
                    % Numeric input fields from second column onwards
                    for col = 2:length(headers)
                        numField = uieditfield(app.ParmetersoftheFrequenciesPanel, 'numeric');
                        numField.Position = [xStart + (col-2)*spacingX, y, fieldWidth, fieldHeight];
                        numField.HorizontalAlignment = 'left';   % Left-align the numeric input
                        
                        % Store handle to access the value later
                        app.FreqInputFields(i, col-1) = numField;
                    end
                    y = y - spacingY;
                end
            end
        
        function startupFcn(app)
            app.ParmetersoftheFrequenciesPanel.Scrollable = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            
                % Calling the function to generate dynamic fields
                app.GenerateFreqInputs();
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            % --- Read static parameters ---
            K = app.NumberofInputFrequenciesEditField.Value;
            % Read other static parameters if any, for example:
            N = app.NumberofReservoirNodesEditField.Value;
            beta1 = app.RegularizationParameterEditField.Value;
            beta2 = app.HarmonicMeanWeightEditField.Value;
            gamma = app.ReservoirConstantEditField.Value;
            T_total = app.TotalTimeStepsEditField.Value;
            T_trans = app.TransientStepsEditField.Value;
            T_train = app.TrainingTimeStepsEditField.Value;
            dt = app.TimeStepLengthEditField.Value;
            multi_num = app.NumberofMultiStartPointsEditField.Value;
            % --- Read dynamic parameters from generated fields ---
            freqParams = zeros(K, size(app.FreqInputFields, 2));  % preallocate
            for i = 1:K
                for j = 1:size(app.FreqInputFields, 2)
                    freqParams(i,j) = app.FreqInputFields(i,j).Value;
                end
            end
            a = freqParams(:,1)';
            b = freqParams(:,2)';
            omega = freqParams(:,3)';
            phi = freqParams(:,4)';
            % --- Call your main function with these parameters ---
            [overalldata, t_train, t_test, y_train, y_test, Omega_train, Omega_test, Omega_train_init, Omega_test_init, optkappa, kappa_init] = maincalc(K, N, beta1, beta2, gamma, T_total, T_trans, T_train, dt, a, b, omega, phi, multi_num);  % Add other parameters if needed
            plotresults(t_train, t_test, y_train, y_test, Omega_train, Omega_test, Omega_train_init, Omega_test_init, optkappa, kappa_init)
            % --- Show or process results ---
            % Now open a new figure and show the table
            fig = uifigure('Name', 'Results Table', 'Position', [100 100 400 200]);
            t = uitable(fig);
            stringData = string(overalldata);
            t.Data = stringData;
            t.ColumnName = {'Normalized Error (Before the Optimization)', 'Normalized Error (After the Optimization)', 'NRMSE (Before the Optimization)', 'NRMSE (After the Optimization)'};
            t.RowName = {'Training Phase', 'Testing Phase'};  % Adjust based on data rows
            t.Position = [20 20 360 260];
            % You can update GUI labels or tables here to show results
            
        end

        % Callback function
        function NumberofInputFrequenciesEditFieldValueChanged(app, event)

        end

        % Callback function
        function PlotButtonValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ReservoirParametersLabel = uilabel(app.GridLayout);
            app.ReservoirParametersLabel.Text = 'Reservoir Parameters';
            app.OptimizationParametersLabel = uilabel(app.GridLayout);
            app.OptimizationParametersLabel.Text = 'Optimization Parameters';
            app.TimeParametersLabel = uilabel(app.GridLayout);
            app.TimeParametersLabel.Text = 'Time Parameters';
            app.NumberofInputFrequenciesEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofInputFrequenciesEditFieldLabel.Text = 'Number of Input Frequencies';
            app.NumberofInputFrequenciesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofInputFrequenciesEditField.Value = 3;
            app.NumberofReservoirNodesEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofReservoirNodesEditFieldLabel.Text = 'Number of Reservoir Nodes';
            app.NumberofReservoirNodesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofReservoirNodesEditField.Value = 10;
            app.RegularizationParameterEditFieldLabel = uilabel(app.GridLayout);
            app.RegularizationParameterEditFieldLabel.Text = 'Regularization Parameter';
            app.RegularizationParameterEditField = uieditfield(app.GridLayout, 'numeric');
            app.RegularizationParameterEditField.Value = 1e-07;
            app.HarmonicMeanWeightEditFieldLabel = uilabel(app.GridLayout);
            app.HarmonicMeanWeightEditFieldLabel.Text = 'Harmonic Mean Weight';
            app.HarmonicMeanWeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.HarmonicMeanWeightEditField.Value = 0.1;
            app.ReservoirConstantEditFieldLabel = uilabel(app.GridLayout);
            app.ReservoirConstantEditFieldLabel.Text = 'Reservoir Constant';
            app.ReservoirConstantEditField = uieditfield(app.GridLayout, 'numeric');
            app.ReservoirConstantEditField.Value = 6;
            app.RegularizationparameterEditFieldLabel_3 = uilabel(app.GridLayout);
            app.RegularizationparameterEditFieldLabel_3.Text = 'Transient Steps';
            app.TransientStepsEditField = uieditfield(app.GridLayout, 'numeric');
            app.TransientStepsEditField.Value = 1000;
            app.RegularizationparameterEditFieldLabel_4 = uilabel(app.GridLayout);
            app.RegularizationparameterEditFieldLabel_4.Text = 'Training Time Steps';
            app.TrainingTimeStepsEditField = uieditfield(app.GridLayout, 'numeric');
            app.TrainingTimeStepsEditField.Value = 3000;
            app.RegularizationparameterEditFieldLabel_5 = uilabel(app.GridLayout);
            app.RegularizationparameterEditFieldLabel_5.Text = 'Time Step Length';
            app.TimeStepLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimeStepLengthEditField.Value = 0.01;
            app.InitializeButton = uibutton(app.GridLayout, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.ParmetersoftheFrequenciesPanel = uipanel(app.GridLayout);
            app.ParmetersoftheFrequenciesPanel.Title = 'Parmeters of the Frequencies';
            app.RunButton = uibutton(app.GridLayout, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.RegularizationparameterEditFieldLabel_6 = uilabel(app.GridLayout);
            app.RegularizationparameterEditFieldLabel_6.Text = 'Total Time Steps';
            app.TotalTimeStepsEditField = uieditfield(app.GridLayout, 'numeric');
            app.TotalTimeStepsEditField.Value = 5000;
            app.NumberofMultiStartPointsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofMultiStartPointsEditFieldLabel.Text = 'Number of Multi-Start Points';
            app.NumberofMultiStartPointsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofMultiStartPointsEditField.Value = 50;
            app.ComputationMethodsLabel = uilabel(app.GridLayout);
            app.ComputationMethodsLabel.Text = 'Computation Methods';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = runapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end